classdef apptraitementdimage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConvolutionfilterButton         matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        HistogranforfilteredButton      matlab.ui.control.Button
        HistogramfororiginalButton      matlab.ui.control.Button
        SegmentthegrayscaleimageButton  matlab.ui.control.Button
        SavemodifiedimageButton         matlab.ui.control.Button
        FilteringimagesEditField        matlab.ui.control.EditField
        WeinerfilterButton              matlab.ui.control.Button
        MedianfilterButton              matlab.ui.control.Button
        AveragefilterButton             matlab.ui.control.Button
        ImportimageButton               matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ImportimageButton
        function ImportimageButtonPushed(app, event)
            global a;
            

[filename, pathname] = uigetfile('*.*', 'Pick An Image');
filename=strcat(pathname,filename);
a=imread(filename);
imshow(a,'Parent',app.UIAxes);

        end

        % Button pushed function: AveragefilterButton
        function AveragefilterButtonPushed(app, event)
            global a;
            global b;
            global c;
            a= getimage(app.UIAxes);
            if isempty(app.UIAxes.Children)
    % The image axes is empty, display a text label
    textLabel = text(app.UIAxes2, 0.5, 0.5, 'Empty Axe', 'HorizontalAlignment', 'center');
    set(textLabel, 'FontSize', 14, 'Color', 'yellow');
    axis(app.UIAxes2, 'off'); % Turn off the axis visibility
            
            else
            b=fspecial("average");
            c=imfilter(a,b);
            imshow(c,'Parent',app.UIAxes2);
            end
        
       
        end

        % Button pushed function: MedianfilterButton
        function MedianfilterButtonPushed(app, event)
            % Check if the image axes is empty
    % Check if the image axes is empty
% Check if the image axes is empty
if isempty(app.UIAxes.Children)
    % The image axes is empty, display a text label
    textLabel = text(app.UIAxes2, 0.5, 0.5, 'Empty Axe', 'HorizontalAlignment', 'center');
    set(textLabel, 'FontSize', 14, 'Color', 'yellow');
    axis(app.UIAxes2, 'off'); % Turn off the axis visibility
else
    % The image axes is not empty, proceed with further operations
    global a;
    global b;
    a = getimage(app.UIAxes);
    
    % Check if the image is RGB
    if size(a, 3) == 3
        a = rgb2gray(a);
    end
    
    b = medfilt2(a);%medfilt w wiener makaysupportewsh rgb kaysupportew 2d
    imshow(b, 'Parent', app.UIAxes2);
end


        end

        % Button pushed function: WeinerfilterButton
        function WeinerfilterButtonPushed(app, event)
   if isempty(app.UIAxes.Children)
    % The image axes is empty, display a text label
    textLabel = text(app.UIAxes2, 0.5, 0.5, 'Empty Axe', 'HorizontalAlignment', 'center');
    set(textLabel, 'FontSize', 14, 'Color', 'yellow');
    axis(app.UIAxes2, 'off'); % Turn off the axis visibility
else
    % The image axes is not empty, proceed with further operations
    global a;
    global b;
    a = getimage(app.UIAxes);
    
    % Check if the image is RGB
    if size(a, 3) == 3
        a = rgb2gray(a);
    end
    
    b = wiener2(a);%medfilt w wiener makaysupportewsh rgb kaysupportew 2d
    imshow(b, 'Parent', app.UIAxes2);
end
        end

        % Button pushed function: SavemodifiedimageButton
        function SavemodifiedimageButtonPushed(app, event)
   % Button pushed function: SavemodifiedimageButton
% Check if the image axes is empty
    if isempty(app.UIAxes.Children )
        % The image axes is empty, display a text label
        textLabel = text(app.UIAxes2, 0.5, 0.5, 'Empty Axe', 'HorizontalAlignment', 'center');
        set(textLabel, 'FontSize', 14, 'Color', 'yellow');
        axis(app.UIAxes2, 'off'); % Turn off the axis visibility
    else
        % The image axes is not empty, proceed with saving the image
        [file, path] = uiputfile({'.png', 'PNG Image (.png)'; '.jpg', 'JPEG Image (.jpg)'; '.bmp', 'Bitmap Image (.bmp)'}, 'Save Image');
        if file ~= 0
            % Get the image from UIAxes2
            img = getimage(app.UIAxes2);
            
            % Save the image to the specified location
            imwrite(img, fullfile(path, file));
            
            % Display a message box confirming the successful save
            msgbox('Image saved successfully.', 'Success');
        end
    end

    
        end

        % Button pushed function: SegmentthegrayscaleimageButton
        function SegmentthegrayscaleimageButtonPushed(app, event)
               if isempty(app.UIAxes.Children)
    % The image axes is empty, display a text label
    textLabel = text(app.UIAxes2, 0.5, 0.5, 'Empty Axe', 'HorizontalAlignment', 'center');
    set(textLabel, 'FontSize', 14, 'Color', 'yellow');
    axis(app.UIAxes2, 'off'); % Turn off the axis visibility
else
    % The image axes is not empty, proceed with further operations
    global a;
    global b;
    a = getimage(app.UIAxes);
    
    % Check if the image is RGB
    if size(a, 3) == 3
        a = im2gray(a);
    end
    
    b = imbinarize(a);%m
    imshow(b, 'Parent', app.UIAxes2);
end
        end

        % Button pushed function: HistogramfororiginalButton
        function HistogramfororiginalButtonPushed(app, event)
            global a;
            a = getimage(app.UIAxes);
           
            imhist(a);
           
        end

        % Button pushed function: HistogranforfilteredButton
        function HistogranforfilteredButtonPushed(app, event)
             global a;
            
            a = getimage(app.UIAxes2);
 
imhist(a);

            
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            global a angle; 
    
            angle = 0; 

    if isempty(app.UIAxes.Children)
        % The image axes is empty, display a text label
        textLabel = text(app.UIAxes2, 0.5, 0.5, 'Empty Axe', 'HorizontalAlignment', 'center');
        set(textLabel, 'FontSize', 14, 'Color', 'yellow');
        axis(app.UIAxes2, 'off'); % Turn off the axis visibility
    else
        % The image axes is not empty, proceed with rotation
        % Get the current image from UIAxes
        a = getimage(app.UIAxes2);

        % Attach the rotation function to the button's 'ButtonPushedFcn' event
        app.RotateButton.ButtonPushedFcn = @rotateImage;
    end

    % Rotation function triggered by the button click
    function rotateImage(~, ~)
        % Rotate the image by -90 degrees (clockwise rotation)
        imgRotated = imrotate(a, -angle);

        % Display the rotated image in UIAxes2
        imshow(imgRotated, 'Parent', app.UIAxes2);

        % Increment the angle by 90 degrees
        angle = angle + 90;
    end


        end

        % Button pushed function: ConvolutionfilterButton
        function ConvolutionfilterButtonPushed(app, event)
             % Obtenir la matrice A, le filtre F et le nombre de fois N
             if isempty(app.UIAxes.Children)
        % The image axes is empty, display a text label
        textLabel = text(app.UIAxes2, 0.5, 0.5, 'Empty Axe', 'HorizontalAlignment', 'center');
        set(textLabel, 'FontSize', 14, 'Color', 'yellow');
        axis(app.UIAxes2, 'off'); % Turn off the axis visibility
             else
                 global A;
                 global F;
                 global N;
             N=20;
             F=[1/9 1/9 1/9;1/9 1/9 1/9;1/9 1/9 1/9];
    A = getimage(app.UIAxes);


% Initialize B with the same size as A
B = zeros(size(A));

% Apply the filtering N times on matrix A
[rows, cols] = size(A);

for k = 1:N
    for i = 2:rows-1
        for j = 2:cols-1
            B(i, j) = F(1, 1) * A(i - 1, j - 1) + F(1, 2) * A(i - 1, j) + F(1, 3) * A(i - 1, j+1) ...
                     + F(2, 1) * A(i, j - 1) + F(2, 2) * A(i , j) + F(2, 3) * A(i, j + 1) ...
                     + F(3, 1) * A(i+1, j - 1) + F(3, 2) * A(i+1, j) + F(3, 3) * A(i+1, j + 1);
        end
    end
    A = B;
end

% Display the transformed matrix B
imshow(uint8(B), 'Parent', app.UIAxes2);

             end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtered image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ImportimageButton = uibutton(app.UIFigure, 'push');
            app.ImportimageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportimageButtonPushed, true);
            app.ImportimageButton.Text = 'Import image';
            app.AveragefilterButton = uibutton(app.UIFigure, 'push');
            app.AveragefilterButton.ButtonPushedFcn = createCallbackFcn(app, @AveragefilterButtonPushed, true);
            app.AveragefilterButton.Text = 'Average filter';
            app.MedianfilterButton = uibutton(app.UIFigure, 'push');
            app.MedianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianfilterButtonPushed, true);
            app.MedianfilterButton.Text = 'Median filter';
            app.WeinerfilterButton = uibutton(app.UIFigure, 'push');
            app.WeinerfilterButton.ButtonPushedFcn = createCallbackFcn(app, @WeinerfilterButtonPushed, true);
            app.WeinerfilterButton.Text = 'Weiner filter';
            app.FilteringimagesEditField = uieditfield(app.UIFigure, 'text');
            app.FilteringimagesEditField.Editable = 'off';
            app.FilteringimagesEditField.Value = '      Filtering images';
            app.SavemodifiedimageButton = uibutton(app.UIFigure, 'push');
            app.SavemodifiedimageButton.ButtonPushedFcn = createCallbackFcn(app, @SavemodifiedimageButtonPushed, true);
            app.SavemodifiedimageButton.Text = 'Save modified image';
            app.SegmentthegrayscaleimageButton = uibutton(app.UIFigure, 'push');
            app.SegmentthegrayscaleimageButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentthegrayscaleimageButtonPushed, true);
            app.SegmentthegrayscaleimageButton.Text = 'Segment the grayscale image';
            app.HistogramfororiginalButton = uibutton(app.UIFigure, 'push');
            app.HistogramfororiginalButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramfororiginalButtonPushed, true);
            app.HistogramfororiginalButton.Text = 'Histogram for original';
            app.HistogranforfilteredButton = uibutton(app.UIFigure, 'push');
            app.HistogranforfilteredButton.ButtonPushedFcn = createCallbackFcn(app, @HistogranforfilteredButtonPushed, true);
            app.HistogranforfilteredButton.Text = 'Histogran for filtered';
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.ConvolutionfilterButton = uibutton(app.UIFigure, 'push');
            app.ConvolutionfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionfilterButtonPushed, true);
            app.ConvolutionfilterButton.Text = 'Convolution filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptraitementdimage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end